classdef CovidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OptionDropDown           matlab.ui.control.DropDown
        OptionDropDownLabel      matlab.ui.control.Label
        DataToPlotDropDown       matlab.ui.control.DropDown
        DatatoPlotDropDownLabel  matlab.ui.control.Label
        AvgDaysSlider            matlab.ui.control.Slider
        AvgDaysSliderLabel       matlab.ui.control.Label
        StateListBox             matlab.ui.control.ListBox
        StateListBoxLabel        matlab.ui.control.Label
        CountryListBox           matlab.ui.control.ListBox
        CountryListBoxLabel      matlab.ui.control.Label
        SearchEditField          matlab.ui.control.EditField
        SearchEditFieldLabel     matlab.ui.control.Label
        EndDatePicker            matlab.ui.control.DatePicker
        EndDateDatePickerLabel   matlab.ui.control.Label
        StartDatePicker          matlab.ui.control.DatePicker
        StartDateLabel           matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        GlobalData CovidData       % single object holding the whole hierarchy
        AllCountries string        % "Global" + every country name (unfiltered)
    end
    
    methods (Access = private)
        
        function obj = currentObject(app)
        % The CovidData object selected in the two list boxes.
        cname = string(app.CountryListBox.Value);
        sname = string(app.StateListBox.Value);
        if cname == "Global"
            obj = app.GlobalData;
            return
        end
        obj = app.GlobalData.findChild(cname);
        if sname ~= "All"
            obj = obj.findChild(sname);
        end
    end

    function name = currentName(app)
        % Readable name for the plot title.
        cname = string(app.CountryListBox.Value);
        sname = string(app.StateListBox.Value);
        if cname == "Global" || sname == "All"
            name = cname;
        else
            name = sname + ", " + cname;
        end
    end

    function refreshStates(app)
        % Fill the state list box for the selected country ("All" first).
        cname = string(app.CountryListBox.Value);
        if cname == "Global"
            states = strings(0, 1);
        else
            states = app.GlobalData.findChild(cname).childNames();
        end
        app.StateListBox.Items = cellstr(["All"; states]);
        app.StateListBox.Value = 'All';
    end

    function filterCountries(app, txt)
        % Show only countries containing txt ("Global" is always listed).
        names = app.AllCountries;
        txt = strtrim(string(txt));
        if strlength(txt) > 0
            keep = [true; contains(names(2:end), txt, 'IgnoreCase', true)];
        else
            keep = true(size(names));
        end
        prev = string(app.CountryListBox.Value);
        app.CountryListBox.Items = cellstr(names(keep));
        if any(names(keep) == prev)
            app.CountryListBox.Value = char(prev);   % keep the selection if still listed
        end
        app.refreshStates();
        app.updatePlot();
    end

    function updatePlot(app, n)
        % n (optional): moving-average length; defaults to the slider value.
        if nargin < 2
            n = round(app.AvgDaysSlider.Value);
        end
        n = min(max(round(n), 1), 15);

        obj    = app.currentObject();
        daily  = string(app.OptionDropDown.Value) == "Daily";
        what   = string(app.DataToPlotDropDown.Value);
        mask   = obj.Dates >= app.StartDatePicker.Value & ...
                 obj.Dates <= app.EndDatePicker.Value;
        x      = obj.Dates(mask);

        ax = app.UIAxes;
        cla(ax, 'reset');                 % also removes any right-hand axis
        if ~any(mask), return, end        % empty date range

        % Moving average is computed on the full series, then cropped to the
        % date range, so the first days of the range still use earlier data.
        yc = obj.series("cases",  daily, n);  yc = yc(mask);
        yd = obj.series("deaths", daily, n);  yd = yd(mask);

        if what == "Both"
            yyaxis(ax, 'left');   plot(ax, x, yc, 'LineWidth', 1.5);
            ylabel(ax, 'Cases');
            yyaxis(ax, 'right');  plot(ax, x, yd, 'LineWidth', 1.5);
            ylabel(ax, 'Deaths');
            what_txt = "cases and deaths";
        elseif what == "Cases"
            plot(ax, x, yc, 'LineWidth', 1.5);  ylabel(ax, 'Cases');
            what_txt = "cases";
        else
            plot(ax, x, yd, 'LineWidth', 1.5);  ylabel(ax, 'Deaths');
            what_txt = "deaths";
        end

        if daily, mode_txt = "Daily"; else, mode_txt = "Cumulative"; end
        if n == 1, avg_txt = "no averaging";
        else,      avg_txt = n + "-day moving average"; end

        title(ax, sprintf('%s: %s %s (%s)', app.currentName(), ...
              mode_txt, what_txt, avg_txt));
        xlabel(ax, 'Date');
        grid(ax, 'on');
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        s = load('covid_data.mat', 'covid_data');        % same folder / on the path
        app.GlobalData   = CovidData.buildGlobal(s.covid_data);
        app.AllCountries = ["Global"; app.GlobalData.childNames()];

        app.CountryListBox.Items = cellstr(app.AllCountries);
        app.CountryListBox.Value = 'Global';
        app.refreshStates();

        d = app.GlobalData.Dates;                         % date range comes from the data
        app.StartDatePicker.Limits = [d(1) d(end)];
        app.EndDatePicker.Limits   = [d(1) d(end)];
        app.StartDatePicker.Value  = d(1);
        app.EndDatePicker.Value    = d(end);

        app.AvgDaysSlider.Limits = [1 15];
        app.AvgDaysSlider.Value  = 1;
        app.updatePlot();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.refreshStates();
            app.updatePlot();
            
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            app.updatePlot();
            
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            app.filterCountries(event.Value);
            
        end

        % Value changed function: AvgDaysSlider
        function AvgDaysSliderValueChanged(app, event)
            app.AvgDaysSlider.Value = round(app.AvgDaysSlider.Value);   % snap to an integer
        app.updatePlot();
            
        end

        % Value changing function: AvgDaysSlider
        function AvgDaysSliderValueChanging(app, event)
             app.updatePlot(round(event.Value));               % live update while dragging

        end

        % Callback function: not associated with a component
        function DataToPlotDropDownValueChanged(app, event)
            app.updatePlot();
            
        end

        % Value changed function: DataToPlotDropDown, EndDatePicker, 
        % ...and 2 other components
        function OptionChanged(app, event)
            app.updatePlot();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartDateLabel = uilabel(app.UIFigure);
            app.StartDateLabel.Text = 'StartDate:';
            app.StartDatePicker = uidatepicker(app.UIFigure);
            app.StartDatePicker.ValueChangedFcn = createCallbackFcn(app, @OptionChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.UIFigure);
            app.EndDateDatePickerLabel.Text = 'EndDate:';
            app.EndDatePicker = uidatepicker(app.UIFigure);
            app.EndDatePicker.ValueChangedFcn = createCallbackFcn(app, @OptionChanged, true);
            app.SearchEditFieldLabel = uilabel(app.UIFigure);
            app.SearchEditFieldLabel.Text = 'Search:';
            app.SearchEditField = uieditfield(app.UIFigure, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateListBoxLabel = uilabel(app.UIFigure);
            app.StateListBoxLabel.Text = 'State:';
            app.StateListBox = uilistbox(app.UIFigure);
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.AvgDaysSliderLabel = uilabel(app.UIFigure);
            app.AvgDaysSliderLabel.Text = 'Avg. Days:';
            app.AvgDaysSlider = uislider(app.UIFigure);
            app.AvgDaysSlider.Limits = [1 15];
            app.AvgDaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AvgDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AvgDaysSliderValueChanged, true);
            app.AvgDaysSlider.ValueChangingFcn = createCallbackFcn(app, @AvgDaysSliderValueChanging, true);
            app.AvgDaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AvgDaysSlider.Value = 1;
            app.DatatoPlotDropDownLabel = uilabel(app.UIFigure);
            app.DatatoPlotDropDownLabel.Text = 'Data to Plot:';
            app.DataToPlotDropDown = uidropdown(app.UIFigure);
            app.DataToPlotDropDown.Items = {'Cases', 'Deaths', 'Both'};
            app.DataToPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionChanged, true);
            app.DataToPlotDropDown.Value = 'Cases';
            app.OptionDropDownLabel = uilabel(app.UIFigure);
            app.OptionDropDownLabel.Text = 'Option:';
            app.OptionDropDown = uidropdown(app.UIFigure);
            app.OptionDropDown.Items = {'Cumulative', 'Daily'};
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionChanged, true);
            app.OptionDropDown.Value = 'Cumulative';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end